classdef LCD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LifeCycleDesignerUIFigure  matlab.ui.Figure
        fileMenu                   matlab.ui.container.Menu
        newMenu                    matlab.ui.container.Menu
        openMenu                   matlab.ui.container.Menu
        importMenu                 matlab.ui.container.Menu
        saveMenu                   matlab.ui.container.Menu
        mappingMenu                matlab.ui.container.Menu
        mappingeditorMenu          matlab.ui.container.Menu
        settingsMenu               matlab.ui.container.Menu
        indicatormethodMenu        matlab.ui.container.Menu
        indicatorcategoryMenu      matlab.ui.container.Menu
        lifelinkdirectoryMenu      matlab.ui.container.Menu
        loadMatrixMenu             matlab.ui.container.Menu
        Btn_LifeLink               matlab.ui.control.StateButton
        CB_ShowFeatures            matlab.ui.control.CheckBox
        TV_Components              matlab.ui.container.Tree
        P_Component                matlab.ui.container.Panel
        materialDropDownLabel      matlab.ui.control.Label
        DD_Material                matlab.ui.control.DropDown
        processtypeDropDownLabel   matlab.ui.control.Label
        DD_ProcessType             matlab.ui.control.DropDown
        BTN_InsertComponent        matlab.ui.control.Button
        CB_IgnoreComponent         matlab.ui.control.CheckBox
        analysisPanel              matlab.ui.container.Panel
        BTN_AnalyseComponent       matlab.ui.control.Button
        componentLabel             matlab.ui.control.Label
        productsystemLabel         matlab.ui.control.Label
        BTN_AnalyseProductSystem   matlab.ui.control.Button
        BTN_SetBenchmark           matlab.ui.control.Button
        BTN_CompareBenchmark       matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        P_Settings                 matlab.ui.container.Panel
        BTN_Open                   matlab.ui.control.Button
        L_Stage                    matlab.ui.control.Label
        CB_ShowImportedStructure   matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        mappingApp
        fileName = 'C:\Users\Z420\Desktop\exchange_folder\F-BOM.xml'; %Xml Dir        
        I_asm
        model = LCModel('mainModel');   
        activeElement       
        mode = "LCModel" %can be 'Stage' or 'Process'
        stageId
    end
    
    properties (Access = public)       
        selectionId = 0;
        A_inv
        A_min
        B
        LCIA
        Q
        com
        ie        
    end
    
    methods (Access = public)       
        
        function [coordinateSelected, minIdx] = getCoord(app, hObj, event) 
            x = hObj.XData; 
            y = hObj.YData; 
            z = hObj.ZData; 
            pt = event.IntersectionPoint;       
            coordinates = [x(:),y(:),z(:)];     
            dist = pdist2(pt,coordinates);      
            [~, minIdx] = min(dist);            
            coordinateSelected = coordinates(minIdx,:);                                              
            app.SetOptions(minIdx,hObj);                        
        end
        
        function SetOptions(app,id,hObj)                                                              
            if (app.mode == "Stage")
                if app.selectionId > 0
                highlight(hObj,app.selectionId,'MarkerSize',30);
                end
                highlight(hObj,id,'MarkerSize',40);
                app.selectionId = id; 
                app.P_Settings.Title = app.activeElement.stages(app.stageId).processList(id);
            elseif (app.mode =="LCModel")
                if app.selectionId > 0
                highlight(hObj,app.selectionId,'MarkerSize',30);
                end
                highlight(hObj,id,'MarkerSize',40);
                app.P_Settings.Title = app.activeElement.stages(id).name;
                app.stageId = id;  
                app.selectionId = id; 
            elseif (app.mode == "Process")
                %nothing yet
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            importMenuSelected(app);            
        end

        % Menu selected function: mappingeditorMenu
        function mappingeditorMenuSelected(app, event)
            app.mappingApp = Mapping;
        end

        % Menu selected function: importMenu
        function importMenuSelected(app, event)
            
            c=xml2struct(app.fileName);                                     
            app.I_asm = struct2I_Assembly(c);            
            app.TV_Components.Children.delete;
            value = app.CB_ShowFeatures.Value;
            DisplayI_Assembly(app.TV_Components,app.I_asm,value);
            expand(app.TV_Components,'all');              
            
            
        end

        % Value changed function: CB_ShowFeatures
        function CB_ShowFeaturesValueChanged(app, event)
            value = app.CB_ShowFeatures.Value;
            app.TV_Components.Children.delete;
            DisplayI_Assembly(app.TV_Components,app.I_asm,value)
            expand(app.TV_Components,'all');                                
            
        end

        % Selection changed function: TV_Components
        function TV_ComponentsSelectionChanged(app, event)
            if app.CB_ShowImportedStructure.Value
                selectedNodes = app.TV_Components.SelectedNodes;                   
                app.activeElement = app.model.assemblies.getElementByName(selectedNodes.NodeData.name);                                                
                app.mode = 'LCModel';
                if isa(app.activeElement, "Component")
                    app.activeElement.displayComponent(app);
                end
                app.BTN_Open.Text = 'open stage';
                %app.activeElement = selectedNodes.NodeData;
                if isprop(app.activeElement,'name')
                    app.P_Component.Title = app.activeElement.name;
                    app.L_Stage.Text = app.activeElement.name;
                else
                    app.P_Component.Title = '';
                    app.L_Stage.Text = '';
                end            
                if isprop(app.activeElement,'material')
                    app.DD_Material.Items = string(app.activeElement.material);                
                else
                    app.DD_Material.Items = cell(0);
                end                
                
            end
        end

        % Button pushed function: BTN_Open
        function BTN_OpenButtonPushed(app, event)
            if app.selectionId > 0
                if  app.mode == "LCModel"
                    app.activeElement.stages(app.selectionId).displayStage(app);
                    app.L_Stage.Text = app.activeElement.name + ' / ' + app.activeElement.stages(app.selectionId).name;
                    app.mode = "Stage";
                    app.BTN_Open.Text = "open process";
                    app.selectionId = 0;
                elseif app.mode == "Stage"
                    activityName = 'casting, aluminium, lost-wax'; 
                    activityLoc = 'RoW';
                    ecoP = EcoinventProcess(app,activityName,activityLoc);                                        
                    ecoP.displayProcess(app);
                    app.L_Stage.Text = app.activeElement.name + ' / ' + app.activeElement.stages(app.selectionId).name + ' / ' + activityName;
                    app.mode = "Process";
                    app.selectionId = 0;
                end
            end
        end

        % Value changed function: CB_ShowImportedStructure
        function CB_ShowImportedStructureValueChanged(app, event)
            value = app.CB_ShowImportedStructure.Value;
            
            if value
                app.CB_ShowFeatures.Enable = false;
                app.TV_Components.Children.delete;
                app.model.assemblies = I_Assembly2Assembly(app.I_asm);
                app.model.assemblies.displayAssembly(app.TV_Components)
                expand(app.TV_Components,'all');                    
            else                            
                app.CB_ShowFeaturesValueChanged();                               
                app.CB_ShowFeatures.Enable = true;                
            end
        end

        % Menu selected function: loadMatrixMenu
        function loadMatrixMenuSelected(app, event)
            h = waitbar(20,['import matrices...']);
            load('LciMat');
            waitbar(50,h,['load matrices...']);
            app.A_inv = A_inv;
            app.A_min= A_min;
            app.B = B;
            app.LCIA = LCIA;
            app.Q = Q;
            waitbar(100,h,['load matrices...']);
            app.com = com;
            app.ie = ie;
            close(h)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LifeCycleDesignerUIFigure = uifigure('Visible', 'off');
            app.LifeCycleDesignerUIFigure.Name = 'Life Cycle Designer';
            app.fileMenu = uimenu(app.LifeCycleDesignerUIFigure);
            app.fileMenu.Text = 'file';
            app.newMenu = uimenu(app.fileMenu);
            app.newMenu.Text = 'new';
            app.openMenu = uimenu(app.fileMenu);
            app.openMenu.Text = 'open';
            app.importMenu = uimenu(app.fileMenu);
            app.importMenu.MenuSelectedFcn = createCallbackFcn(app, @importMenuSelected, true);
            app.importMenu.Text = 'import';
            app.saveMenu = uimenu(app.fileMenu);
            app.saveMenu.Text = 'save';
            app.mappingMenu = uimenu(app.LifeCycleDesignerUIFigure);
            app.mappingMenu.Text = 'mapping';
            app.mappingeditorMenu = uimenu(app.mappingMenu);
            app.mappingeditorMenu.MenuSelectedFcn = createCallbackFcn(app, @mappingeditorMenuSelected, true);
            app.mappingeditorMenu.Text = 'mapping editor';
            app.settingsMenu = uimenu(app.LifeCycleDesignerUIFigure);
            app.settingsMenu.Text = 'settings';
            app.indicatormethodMenu = uimenu(app.settingsMenu);
            app.indicatormethodMenu.Text = 'indicator method';
            app.indicatorcategoryMenu = uimenu(app.settingsMenu);
            app.indicatorcategoryMenu.Text = 'indicator category';
            app.lifelinkdirectoryMenu = uimenu(app.settingsMenu);
            app.lifelinkdirectoryMenu.Text = 'life link directory';
            app.loadMatrixMenu = uimenu(app.settingsMenu);
            app.loadMatrixMenu.MenuSelectedFcn = createCallbackFcn(app, @loadMatrixMenuSelected, true);
            app.loadMatrixMenu.Text = 'load Matrix';
            app.Btn_LifeLink = uibutton(app.LifeCycleDesignerUIFigure, 'state');
            app.Btn_LifeLink.Text = 'life link';
            app.CB_ShowFeatures = uicheckbox(app.LifeCycleDesignerUIFigure);
            app.CB_ShowFeatures.ValueChangedFcn = createCallbackFcn(app, @CB_ShowFeaturesValueChanged, true);
            app.CB_ShowFeatures.Text = 'show features & CAM';
            app.CB_ShowFeatures.Value = true;
            app.TV_Components = uitree(app.LifeCycleDesignerUIFigure);
            app.TV_Components.SelectionChangedFcn = createCallbackFcn(app, @TV_ComponentsSelectionChanged, true);
            app.P_Component = uipanel(app.LifeCycleDesignerUIFigure);
            app.P_Component.Title = ' ';
            app.materialDropDownLabel = uilabel(app.P_Component);
            app.materialDropDownLabel.Text = 'material';
            app.DD_Material = uidropdown(app.P_Component);
            app.DD_Material.Items = {};
            app.DD_Material.Value = {};
            app.processtypeDropDownLabel = uilabel(app.P_Component);
            app.processtypeDropDownLabel.Text = 'process type';
            app.DD_ProcessType = uidropdown(app.P_Component);
            app.DD_ProcessType.Items = {};
            app.DD_ProcessType.Value = {};
            app.BTN_InsertComponent = uibutton(app.P_Component, 'push');
            app.BTN_InsertComponent.Text = 'insert component';
            app.CB_IgnoreComponent = uicheckbox(app.P_Component);
            app.CB_IgnoreComponent.Text = 'ignore component';
            app.analysisPanel = uipanel(app.LifeCycleDesignerUIFigure);
            app.analysisPanel.Title = ' analysis';
            app.BTN_AnalyseComponent = uibutton(app.analysisPanel, 'push');
            app.BTN_AnalyseComponent.Text = 'analyse component';
            app.componentLabel = uilabel(app.analysisPanel);
            app.componentLabel.Text = 'component';
            app.productsystemLabel = uilabel(app.analysisPanel);
            app.productsystemLabel.Text = 'product system';
            app.BTN_AnalyseProductSystem = uibutton(app.analysisPanel, 'push');
            app.BTN_AnalyseProductSystem.Text = 'analyse product system';
            app.BTN_SetBenchmark = uibutton(app.analysisPanel, 'push');
            app.BTN_SetBenchmark.Text = 'set as benchmark';
            app.BTN_CompareBenchmark = uibutton(app.analysisPanel, 'push');
            app.BTN_CompareBenchmark.Text = 'compare to benchmark';
            app.UIAxes = uiaxes(app.LifeCycleDesignerUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = 0;
            app.UIAxes.YTickLabel = '';
            app.P_Settings = uipanel(app.LifeCycleDesignerUIFigure);
            app.P_Settings.Title = ' ';
            app.BTN_Open = uibutton(app.P_Settings, 'push');
            app.BTN_Open.ButtonPushedFcn = createCallbackFcn(app, @BTN_OpenButtonPushed, true);
            app.BTN_Open.Text = 'open stage';
            app.L_Stage = uilabel(app.LifeCycleDesignerUIFigure);
            app.L_Stage.Text = '';
            app.CB_ShowImportedStructure = uicheckbox(app.LifeCycleDesignerUIFigure);
            app.CB_ShowImportedStructure.ValueChangedFcn = createCallbackFcn(app, @CB_ShowImportedStructureValueChanged, true);
            app.CB_ShowImportedStructure.Text = 'show imported structure';
            % Show the figure after all components are created
            app.LifeCycleDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LCD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LifeCycleDesignerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LifeCycleDesignerUIFigure)
        end
    end
end